classdef MATLAB_GPIO_MQTT_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GPIOMQTTUIFigure   matlab.ui.Figure
        SendLEDsButton     matlab.ui.control.Button
        GreenSwitch        matlab.ui.control.ToggleSwitch
        GreenSwitchLabel   matlab.ui.control.Label
        YellowSwitch       matlab.ui.control.ToggleSwitch
        YellowSwitchLabel  matlab.ui.control.Label
        RedSwitch          matlab.ui.control.ToggleSwitch
        RedSwitchLabel     matlab.ui.control.Label
        CounterLabel       matlab.ui.control.Label
        GreenOffButton     matlab.ui.control.Button
        GreenOnButton      matlab.ui.control.Button
        YellowOffButton    matlab.ui.control.Button
        YellowOnButton     matlab.ui.control.Button
        RedOffButton       matlab.ui.control.Button
        RedOnButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        mqClient
        counter = 0;
    end
    
    methods (Access = private)

        function myCallback(app, topic, message)
            fprintf("Received: %s\n", message);
            messageStruct = jsondecode(message);
            messageType = messageStruct.type

            % if ~isempty(messageStruct.payload)
            %     messagePayload = messageStruct.payload
            % end

            if messageType == "button"
                app.counter = app.counter + 1;
                app.CounterLabel.Text = sprintf("Counter = %d", app.counter);
            end


            if messageType == "reset"
                app.counter = 0;
                app.CounterLabel.Text = sprintf("Counter = %d", app.counter);
            end


        end

        function sendMessage(app, messageType, messagePayload)
            messageStruct.type = messageType;
            messageStruct.payload = messagePayload;
            messageJson = jsonencode(messageStruct);

            % app.mqClient.write("chat", messageJson);

            write(app.mqClient, "me435/fisherds/to_pi", messageJson);
            fprintf("Sent: %s\n", messageJson);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.mqClient = mqttclient("tcp://mosquitto.csse.rose-hulman.edu");
            app.mqClient.subscribe("me435/fisherds/to_computer", "Callback", @app.myCallback);

        end

        % Button pushed function: RedOnButton
        function RedOnButtonPushed(app, event)
            app.sendMessage("red", "on");
        end

        % Button pushed function: RedOffButton
        function RedOffButtonPushed(app, event)
            app.sendMessage("red", "off");
        end

        % Button pushed function: YellowOnButton
        function YellowOnButtonPushed(app, event)
            app.sendMessage("yellow", 1);
        end

        % Button pushed function: YellowOffButton
        function YellowOffButtonPushed(app, event)
            app.sendMessage("yellow", 0);
        end

        % Button pushed function: GreenOnButton
        function GreenOnButtonPushed(app, event)
            app.sendMessage("green", true);
        end

        % Button pushed function: GreenOffButton
        function GreenOffButtonPushed(app, event)
            app.sendMessage("green", false);
        end

        % Button pushed function: SendLEDsButton
        function SendLEDsButtonPushed(app, event)
            led_values = [0 0 0];
            if app.RedSwitch.Value == "On"
                led_values(1) = 1;
            end
            if app.YellowSwitch.Value == "On"
                led_values(2) = 1;
            end
            if app.GreenSwitch.Value == "On"
                led_values(3) = 1;
            end
            app.sendMessage("leds", led_values);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GPIOMQTTUIFigure = uifigure('Visible', 'off');
            app.GPIOMQTTUIFigure.Name = 'GPIO MQTT';
            app.RedOnButton = uibutton(app.GPIOMQTTUIFigure, 'push');
            app.RedOnButton.ButtonPushedFcn = createCallbackFcn(app, @RedOnButtonPushed, true);
            app.RedOnButton.Text = 'Red On';
            app.RedOffButton = uibutton(app.GPIOMQTTUIFigure, 'push');
            app.RedOffButton.ButtonPushedFcn = createCallbackFcn(app, @RedOffButtonPushed, true);
            app.RedOffButton.Text = 'Red Off';
            app.YellowOnButton = uibutton(app.GPIOMQTTUIFigure, 'push');
            app.YellowOnButton.ButtonPushedFcn = createCallbackFcn(app, @YellowOnButtonPushed, true);
            app.YellowOnButton.Text = 'Yellow On';
            app.YellowOffButton = uibutton(app.GPIOMQTTUIFigure, 'push');
            app.YellowOffButton.ButtonPushedFcn = createCallbackFcn(app, @YellowOffButtonPushed, true);
            app.YellowOffButton.Text = 'Yellow Off';
            app.GreenOnButton = uibutton(app.GPIOMQTTUIFigure, 'push');
            app.GreenOnButton.ButtonPushedFcn = createCallbackFcn(app, @GreenOnButtonPushed, true);
            app.GreenOnButton.Text = 'Green On';
            app.GreenOffButton = uibutton(app.GPIOMQTTUIFigure, 'push');
            app.GreenOffButton.ButtonPushedFcn = createCallbackFcn(app, @GreenOffButtonPushed, true);
            app.GreenOffButton.Text = 'Green Off';
            app.CounterLabel = uilabel(app.GPIOMQTTUIFigure);
            app.CounterLabel.Text = 'Counter ';
            app.RedSwitchLabel = uilabel(app.GPIOMQTTUIFigure);
            app.RedSwitchLabel.Text = 'Red';
            app.RedSwitch = uiswitch(app.GPIOMQTTUIFigure, 'toggle');
            app.YellowSwitchLabel = uilabel(app.GPIOMQTTUIFigure);
            app.YellowSwitchLabel.Text = 'Yellow';
            app.YellowSwitch = uiswitch(app.GPIOMQTTUIFigure, 'toggle');
            app.GreenSwitchLabel = uilabel(app.GPIOMQTTUIFigure);
            app.GreenSwitchLabel.Text = 'Green';
            app.GreenSwitch = uiswitch(app.GPIOMQTTUIFigure, 'toggle');
            app.SendLEDsButton = uibutton(app.GPIOMQTTUIFigure, 'push');
            app.SendLEDsButton.ButtonPushedFcn = createCallbackFcn(app, @SendLEDsButtonPushed, true);
            app.SendLEDsButton.Text = 'Send LEDs';
            % Show the figure after all components are created
            app.GPIOMQTTUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MATLAB_GPIO_MQTT_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GPIOMQTTUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GPIOMQTTUIFigure)
        end
    end
end